classdef photoApproval < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TakePictureButton      matlab.ui.control.Button
        O2BublChefKiss         matlab.ui.control.Label
        YesIlikethisoneButton  matlab.ui.control.Button
        RetakepleaseButton     matlab.ui.control.Button
        Image                  matlab.ui.control.Image
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TakePictureButton
        function TakePictureButtonPushed(app, event)
            cam = webcam;
            frame = snapshot(cam);
            im = image(app.UIAxes, zeros(size(frame),'uint8'));
            axis(app.UIAxes, 'image');
            
            preview(cam, im);
            
            pause; 
        end

        % Button pushed function: YesIlikethisoneButton
        function YesIlikethisoneButtonPushed(app, event)
        function createLoadingBar()
    % Create a figure and a button
    fig = figure('Name', 'Loading Bar Example');
    btn = uicontrol('Style', 'pushbutton', 'String', 'Start Loading', ...
                    'Position', [20, 20, 100, 30], 'Callback', @startLoading);

    function startLoading(~, ~)
        % Disable the button during loading
        btn.Enable = 'off';
        
        % Create a waitbar with 0% progress
        h = waitbar(0, 'Loading...');
        
        % Simulate loading process
        for i = 1:100
            % Update waitbar progress
            waitbar(i/100, h, sprintf('Loading... %d%%', i));
            
            % Pause to simulate loading time
            pause(0.1);
        end
        
        % Close the waitbar after loading
        close(h);
        
        % Enable the button after loading is complete
        btn.Enable = 'on';
    end
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'yy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'o2-bubl-chefskiss.gif');
            app.RetakepleaseButton = uibutton(app.UIFigure, 'push');
            app.RetakepleaseButton.Text = 'Retake please!';
            app.YesIlikethisoneButton = uibutton(app.UIFigure, 'push');
            app.YesIlikethisoneButton.ButtonPushedFcn = createCallbackFcn(app, @YesIlikethisoneButtonPushed, true);
            app.YesIlikethisoneButton.Text = 'Yes, I like this one';
            app.O2BublChefKiss = uilabel(app.UIFigure);
            app.O2BublChefKiss.Text = 'Looking wonderful! Would you like to retake the photo or use this one?';
            app.TakePictureButton = uibutton(app.UIFigure, 'push');
            app.TakePictureButton.ButtonPushedFcn = createCallbackFcn(app, @TakePictureButtonPushed, true);
            app.TakePictureButton.Text = 'TakePicture';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = photoApproval

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end