classdef testLoadingBar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TestLoadingBarButton  matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestLoadingBarButton
        function TestLoadingBarButtonPushed(app, event)
       function createLoadingBar()
    % Create a figure and a button
    fig = figure('Name', 'Loading Bar Example');
    btn = uicontrol('Style', 'pushbutton', 'String', 'Start Loading', ...
                    'Position', [20, 20, 100, 30], 'Callback', @startLoading);

    function startLoading(~, ~)
        % Disable the button during loading
        btn.Enable = 'off';

        % Create a waitbar with 0% progress
        h = waitbar(0, 'Loading...');

        % Simulate loading process
        for i = 1:100
            % Update waitbar progress
            waitbar(i/100, h, sprintf('Loading... %d%%', i));

            % Pause to simulate loading time
            pause(0.1);
        end

        % Close the waitbar after loading
        close(h);

        % Enable the button after loading is complete
        btn.Enable = 'on';
    end
       end
       createLoadingBar();

   % Button pushed function: TestLoadingBarButton

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TestLoadingBarButton = uibutton(app.UIFigure, 'push');
            app.TestLoadingBarButton.ButtonPushedFcn = createCallbackFcn(app, @TestLoadingBarButtonPushed, true);
            app.TestLoadingBarButton.Text = 'Test Loading Bar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testLoadingBar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end